classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        SmoothenedCentrelineLabel   matlab.ui.control.Label
        PrunedCentrelineLabel       matlab.ui.control.Label
        RoughCentrelineLabel        matlab.ui.control.Label
        PrintcentrelineastxtButton  matlab.ui.control.Button
        TransparencySlider          matlab.ui.control.Slider
        TransparencySliderLabel     matlab.ui.control.Label
        FinenessSlider              matlab.ui.control.Slider
        FinenessSliderLabel         matlab.ui.control.Label
        SmoothenedSkelSwitch        matlab.ui.control.Switch
        SmoothenedSkelSwitchLabel   matlab.ui.control.Label
        PrunedSkelSwitch            matlab.ui.control.Switch
        PrunedSkelSwitchLabel       matlab.ui.control.Label
        AortaSwitch_3               matlab.ui.control.Switch
        AortaSwitchLabel_3          matlab.ui.control.Label
        VoxelInfill                 matlab.ui.control.Switch
        AortaSwitchLabel_2          matlab.ui.control.Label
        AortaModelSwitch            matlab.ui.control.Switch
        AortaModelSwitchLabel       matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
%         Property % Description
    FV
    interior
    skeleton
    adj_matrix 
    spline_faces
    spline_vertices
    skel_xyz
    pruned_x
    pruned_y
    pruned_z
    pruned_skel
    fine
    smooth
    transparency
    aortaModel
    spline_x
    spline_y
    spline_z

    aortaPatch
    voxelPatch
    skeletonPatch
    prunedskeletonPlot
    smoothSkelPlot

    x_scaled
    y_scaled
    z_scaled
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = load('Mesh_Soft_EM_Mod_0601.mat');
            app.FV.vertices = data.ver;
            app.FV.faces = data.tri;

            app.fine=100;
            app.transparency=0.2;
            app.aortaModel=1;

            app.aortaPatch=patch(app.FV,'facecolor',[1 0 0],'facealpha',0.2,'edgecolor','none','DisplayName','Aorta Model');
            view(3)
            camlight
            axis equal;
            hold on
            legend;
            grid on

            app.interior = fillVoxel(app.FV);
            app.skeleton = bwskel(logical(app.interior));


            [y, x, z] = ind2sub(size(app.skeleton), find(app.skeleton));
            scaleFactor = [0.95, 0.95, 0.95]; % [scaleX, scaleY, scaleZ]
            translation = [-15 , -126 ,-205 ]; % [translateX, translateY, translateZ]
            
            x = x * scaleFactor(1) + translation(1);
            y = y * scaleFactor(2) + translation(2);
            z = z * scaleFactor(3) + translation(3);

            threshold = 2.0;
            app.adj_matrix = AdjMatrix([x y z], threshold);
            [app.pruned_skel(:,1),app.pruned_skel(:,2),app.pruned_skel(:,3)]=pruneOffBranch(app.adj_matrix, x, y , z , 197, 5);
  
            [app.x_scaled, app.y_scaled, app.z_scaled] = meshgrid(1:200, 1:200, 1:200);
         
            app.x_scaled = app.x_scaled * scaleFactor(1) + translation(1);
            app.y_scaled = app.y_scaled * scaleFactor(2) + translation(2);
            app.z_scaled = app.z_scaled * scaleFactor(3) + translation(3);


            [app.spline_x, app.spline_y, app.spline_z]=smoothSkel(app.pruned_skel,app.fine);
        end

        % Value changed function: AortaModelSwitch
        function AortaModelSwitchValueChanged(app, event)
            value = app.AortaModelSwitch.Value;
             if strcmp(value, 'On') 
                app.aortaPatch=patch(app.FV,'facecolor',[1 0 0],'facealpha',app.transparency,'edgecolor','none','DisplayName','Aorta Model');
                app.aortaModel=1;
            else
                delete(app.aortaPatch)
                app.aortaModel=0;
            end
        end

        % Value changed function: VoxelInfill
        function VoxelInfillValueChanged(app, event)
            value = app.VoxelInfill.Value;
            if strcmp(value, 'On') 
                app.voxelPatch = patch(isosurface(app.x_scaled, app.y_scaled, app.z_scaled, app.interior, 0.5),'DisplayName','Fill Inside');
            else
                delete(app.voxelPatch)
            end
        end

        % Value changed function: AortaSwitch_3
        function AortaSwitch_3ValueChanged(app, event)
            value = app.AortaSwitch_3.Value;
            if strcmp(value, 'On') 


                app.skeletonPatch=patch(isosurface(app.x_scaled, app.y_scaled, app.z_scaled, app.skeleton, 0.5),'facecolor',[0 1 0],'DisplayName','Rough Centreline');

            else
                delete(app.skeletonPatch)
            end
        end

        % Value changed function: PrunedSkelSwitch
        function PrunedSkelSwitchValueChanged(app, event)
            value = app.PrunedSkelSwitch.Value;
                        % Extract coordinates of the skeleton voxels and plot them
            
            if strcmp(value, 'On') 
                     
            app.prunedskeletonPlot=plot3(app.pruned_skel(:,1), app.pruned_skel(:,2), app.pruned_skel(:,3), 'b.','DisplayName', 'Breadth-First Graph Prune', 'MarkerSize', 10);
            else
                delete(app.prunedskeletonPlot)
            end
        end

        % Value changed function: SmoothenedSkelSwitch
        function SmoothenedSkelSwitchValueChanged(app, event)
            value = app.SmoothenedSkelSwitch.Value;

            if strcmp(value, 'On') 
            
            app.smooth=1
            [spline_x, spline_y, spline_z]=smoothSkel(app.pruned_skel,app.fine);

            app.smoothSkelPlot=plot3(spline_x, spline_y, spline_z,'ro', 'DisplayName', 'Cubic B-spline');

            else
                app.smooth=0
                delete(app.smoothSkelPlot)
            end
        end

        % Value changed function: FinenessSlider
        function FinenessSliderValueChanged(app, event)
            value = app.FinenessSlider.Value;
            app.fine=value


            if app.smooth==1 
            
                app.smooth=1
                [spline_x, spline_y, spline_z]=smoothSkel(app.pruned_skel,app.fine);
                delete(app.smoothSkelPlot)
                app.smoothSkelPlot=plot3(spline_x, spline_y, spline_z,'ro', 'DisplayName', 'Cubic B-spline');

            end
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            value = app.TransparencySlider.Value;
            app.transparency=value

            if app.aortaModel==1
                delete(app.aortaPatch)
                app.aortaPatch=patch(app.FV,'facecolor',[1 0 0],'facealpha',app.transparency,'edgecolor','none','DisplayName','Aorta Model');

            end
        end

        % Button pushed function: PrintcentrelineastxtButton
        function PrintcentrelineastxtButtonPushed(app, event)
            tfile(1,:)=app.spline_x;
            tfile(2,:)=app.spline_y; 
            tfile(3,:)=app.spline_z;
            tfile=tfile';
            dlmwrite('SmoothenedCentreline.txt',tfile, 'delimiter', '\t')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AortaModelSwitchLabel = uilabel(app.GridLayout);
            app.AortaModelSwitchLabel.Text = 'Aorta Model';
            app.AortaModelSwitch = uiswitch(app.GridLayout, 'slider');
            app.AortaModelSwitch.ValueChangedFcn = createCallbackFcn(app, @AortaModelSwitchValueChanged, true);
            app.AortaModelSwitch.Value = 'On';
            app.AortaSwitchLabel_2 = uilabel(app.GridLayout);
            app.AortaSwitchLabel_2.Text = 'Voxel Infill';
            app.VoxelInfill = uiswitch(app.GridLayout, 'slider');
            app.VoxelInfill.ValueChangedFcn = createCallbackFcn(app, @VoxelInfillValueChanged, true);
            app.AortaSwitchLabel_3 = uilabel(app.GridLayout);
            app.AortaSwitchLabel_3.Text = 'Skeletonisation';
            app.AortaSwitch_3 = uiswitch(app.GridLayout, 'slider');
            app.AortaSwitch_3.ValueChangedFcn = createCallbackFcn(app, @AortaSwitch_3ValueChanged, true);
            app.PrunedSkelSwitchLabel = uilabel(app.GridLayout);
            app.PrunedSkelSwitchLabel.Text = 'Pruned Skel';
            app.PrunedSkelSwitch = uiswitch(app.GridLayout, 'slider');
            app.PrunedSkelSwitch.ValueChangedFcn = createCallbackFcn(app, @PrunedSkelSwitchValueChanged, true);
            app.SmoothenedSkelSwitchLabel = uilabel(app.GridLayout);
            app.SmoothenedSkelSwitchLabel.Text = 'Smoothened Skel';
            app.SmoothenedSkelSwitch = uiswitch(app.GridLayout, 'slider');
            app.SmoothenedSkelSwitch.ValueChangedFcn = createCallbackFcn(app, @SmoothenedSkelSwitchValueChanged, true);
            app.FinenessSliderLabel = uilabel(app.GridLayout);
            app.FinenessSliderLabel.Text = 'Fineness';
            app.FinenessSlider = uislider(app.GridLayout);
            app.FinenessSlider.Limits = [10 1000];
            app.FinenessSlider.ValueChangedFcn = createCallbackFcn(app, @FinenessSliderValueChanged, true);
            app.FinenessSlider.Value = 10;
            app.TransparencySliderLabel = uilabel(app.GridLayout);
            app.TransparencySliderLabel.Text = 'Transparency';
            app.TransparencySlider = uislider(app.GridLayout);
            app.TransparencySlider.Limits = [0 1];
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.Value = 0.2;
            app.PrintcentrelineastxtButton = uibutton(app.GridLayout, 'push');
            app.PrintcentrelineastxtButton.ButtonPushedFcn = createCallbackFcn(app, @PrintcentrelineastxtButtonPushed, true);
            app.PrintcentrelineastxtButton.Text = 'Print centreline as *.txt';
            app.RoughCentrelineLabel = uilabel(app.GridLayout);
            app.RoughCentrelineLabel.Text = 'Rough Centreline';
            app.PrunedCentrelineLabel = uilabel(app.GridLayout);
            app.PrunedCentrelineLabel.Text = 'Pruned Centreline';
            app.SmoothenedCentrelineLabel = uilabel(app.GridLayout);
            app.SmoothenedCentrelineLabel.Text = 'Smoothened Centreline';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end